classdef SDMapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        ExportsimulationsdataButton     matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        PManrique2024Label              matlab.ui.control.Label
        GeneratereportButton            matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        PowerSpectralDensityGraphPanel  matlab.ui.container.Panel
        ImprovedFOMDesignVariablesLabel  matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        RNNInferredDesignVariablesLabel  matlab.ui.control.Label
        DesignVariablesPanel            matlab.ui.container.Panel
        RuninferencetoperformarchitectureselectionLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        SelectedArchitecturePanel       matlab.ui.container.Panel
        SpecificationsPanel             matlab.ui.container.Panel
        OutputSpecificationssimulationsPanel  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TextArea_Powerwo                matlab.ui.control.TextArea
        TextArea_BWwo                   matlab.ui.control.TextArea
        TextArea_SNRwo                  matlab.ui.control.TextArea
        TextArea_Powerw                 matlab.ui.control.TextArea
        TextArea_BWw                    matlab.ui.control.TextArea
        TextArea_SNRw                   matlab.ui.control.TextArea
        wFOMimprovingLabel              matlab.ui.control.Label
        woFOMimprovingLabel             matlab.ui.control.Label
        RunsimulationsButton            matlab.ui.control.Button
        InputSpecificationsPanel        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        PowermWEditField                matlab.ui.control.NumericEditField
        PowermWEditFieldLabel           matlab.ui.control.Label
        BWMHzEditField                  matlab.ui.control.NumericEditField
        BWMHzEditFieldLabel             matlab.ui.control.Label
        SNRdBEditField                  matlab.ui.control.NumericEditField
        SNRdBEditFieldLabel             matlab.ui.control.Label
        RuninferenceButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SigmaDeltaMAppLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        arch = [] % architecture
        column_names = [] %dvars names
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SNRdBEditField
        function SNRdBEditFieldValueChanged(app, event)
            value = app.SNRdBEditField.Value;
            app.SNRdBEditField.Limits = [50,150];
        end

        % Value changed function: BWMHzEditField
        function BWMHzEditFieldValueChanged(app, event)
            value = app.BWMHzEditField.Value;
            app.BWMHzEditField.Limits = [0.01,200];
        end

        % Value changed function: PowermWEditField
        function PowermWEditFieldValueChanged(app, event)
            value = app.PowermWEditField.Value;
            app.PowermWEditField.Limits = [0.1,100];
        end

        % Button pushed function: RuninferenceButton
        function RuninferenceButtonPushed(app, event)

            app.RuninferenceButton.BackgroundColor = [0.87 0.60 0.60];

            % Inputs
            snr = app.SNRdBEditField.Value;
            assignin('base',"snr",snr)
            bw = 1e6*app.BWMHzEditField.Value;
            assignin('base',"bw",bw)
            save('bw.mat','bw')
            power = app.PowermWEditField.Value / 1e3;
            assignin('base',"power",power)

            fom = snr + 10*log10(bw/power);
            input_specs = [fom,snr,bw,power];
            inspecs_table = array2table(input_specs,'VariableNames',{'FOM','SNR','BW','Power'});
            writetable(inspecs_table,'inspecs.csv')

            % Perform predictions
            pyarch = py.predictions.classifier_prediction(snr,bw,power);
            py.predictions.rnn_prediction(snr,bw,power,pyarch);
            dvars = readtable('dvars.csv');
            save('dvars.mat',"dvars")
            app.arch = string(pyarch);
            architecture = app.arch;
            save('arch.mat','architecture')
            %dvars = table(dfdvars);
            assignin('base',"dvars",dvars)

            % Selected architecture
            app.RuninferencetoperformarchitectureselectionLabel.Enable = "on";
            app.RuninferencetoperformarchitectureselectionLabel.Text = app.arch;
            app.RuninferencetoperformarchitectureselectionLabel.FontColor = 'k';
            impath = strcat('Figures/',app.arch,'.jpg');
            img = imread(impath);
            app.Image.Enable = "on";
            app.Image.ImageSource = img;
            
            % Predicted design vars:
            app.column_names = dvars.Properties.VariableNames;
            app.RNNInferredDesignVariablesLabel.Enable = "on";
            app.UITable.Enable = "on";
            app.UITable.ColumnName = app.column_names;
            app.UITable2.ColumnName = app.column_names; 
            app.UITable.Data = dvars;

            % Enable running simulations
            app.RunsimulationsButton.Enable = "on";

            app.RuninferenceButton.BackgroundColor = [0.60 0.87 0.60];

        end

        % Button pushed function: RunsimulationsButton
        function RunsimulationsButtonPushed(app, event)

            app.RunsimulationsButton.BackgroundColor = [0.87 0.60 0.60];

            app.ImprovedFOMDesignVariablesLabel.Enable = "on";
            

            %Run Random Variations script
            run('randomvariations.m');

            %Run simulations
            sel_arch = app.arch;
            sim_file = strcat('sim',sel_arch,'.m');
            %gp_file = strcat(sel_arch,'_GP.mat');
            %load(gp_file);
            run(sim_file);
            table_sim = readtable('total_sims.csv');
            ann_row = table_sim(1,:);
            opt_idx = find(table_sim.FOM == max(table_sim.FOM));
            opt_row = table_sim(opt_idx,:);
            
            snr_ann = ann_row.SNR;
            snr_opt = opt_row.SNR;
            bw_ann = ann_row.Bw;
            bw_ann_mhz = bw_ann/1e6;
            bw_opt = opt_row.Bw;
            bw_opt_mhz = bw_opt/1e6;
            power_ann = ann_row.Power;
            power_ann_mw = power_ann*1000;
            power_opt = opt_row.Power;
            power_opt_mw = power_opt*1000;

            app.TextArea_SNRwo.Enable = "on";
            app.TextArea_SNRwo.Value = string(snr_ann);
            app.TextArea_SNRw.Enable = "on";
            app.TextArea_SNRw.Value = string(snr_opt);
            app.TextArea_BWwo.Enable = "on";
            app.TextArea_BWwo.Value = string(bw_ann_mhz);
            app.TextArea_BWw.Enable = "on";
            app.TextArea_BWw.Value = string(bw_opt_mhz);
            app.TextArea_Powerwo.Enable = "on";
            app.TextArea_Powerwo.Value = string(power_ann_mw);
            app.TextArea_Powerw.Enable = "on";
            app.TextArea_Powerw.Value = string(power_opt_mw);

            dvars_names = app.column_names;
            dvars_opt = opt_row(:,dvars_names);
            app.UITable2.Data = dvars_opt;

            app.wFOMimprovingLabel.Enable = "on";
            app.woFOMimprovingLabel.Enable = "on";
            app.UITable2.Enable = "on";
            app.GeneratereportButton.Enable = "on";
            app.ExportsimulationsdataButton.Enable = "on";
            app.Image2.Enable = "on";
            app.Image2.ImageSource = 'figPSD.png';


            app.RunsimulationsButton.BackgroundColor = [0.60 0.87 0.60];
        end

        % Callback function
        function ComputeButtonPushed(app, event)
            app.UITable2.Enable = "on";
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.SNRdBEditField.Value = '';
            app.BWMHzEditField.Value = '';
            app.PowermWEditField.Value = '';

            app.woFOMimprovingLabel.Enable = "off";
            app.wFOMimprovingLabel.Enable = "off";

            app.TextArea_SNRwo.Value = '';
            app.TextArea_SNRwo.Enable = "off";
            app.TextArea_BWwo.Value = '';
            app.TextArea_BWwo.Enable = "off";
            app.TextArea_Powerwo.Value = '';
            app.TextArea_Powerwo.Enable = "off";
            app.TextArea_SNRw.Value = '';
            app.TextArea_SNRw.Enable = "off";
            app.TextArea_BWw.Value = '';
            app.TextArea_BWw.Enable = "off";
            app.TextArea_Powerw.Value = '';
            app.TextArea_Powerw.Enable = "off";

            app.RunsimulationsButton.BackgroundColor = [0.80 0.80 0.80];
            app.RunsimulationsButton.Enable = "off";
            
            app.RuninferenceButton.BackgroundColor = [0.80 0.80 0.80];

            app.Image.Enable = "off";
            app.Image.ImageSource = '';
            app.Image2.Enable = "off";
            app.Image2.ImageSource = '';
            app.RuninferencetoperformarchitectureselectionLabel.Enable = "off";
            
            app.UITable.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable.Enable = "off";
            app.UITable.Data = {'','','',''};

            app.UITable2.ColumnName = {'DV1','DV2','DV3','DV4'};
            app.UITable2.Enable = "off";
            app.UITable2.Data = {'','','',''};

            app.GeneratereportButton.Enable = "off";
        end

        % Button pushed function: GeneratereportButton
        function GeneratereportButtonPushed(app, event)
            generate_report;
        end

        % Button pushed function: ExportsimulationsdataButton
        function ExportsimulationsdataButtonPushed(app, event)
            table_totsims = readtable('total_sims.csv');
            [file,location] = uiputfile('simsdata.csv','Save data');
            
            if file ~= 0 && location ~= 0
                path = strcat(location,file);
                writetable(table_totsims,path)
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            if app.Image.Enable == "on"
                model = app.arch;
                switch model
                    case '2ndGmCSDM'
                        GmC2ndCTSDMParam
                    case '2ndSCSDM_MultiBit'
                        SecondOrderMultibitSC
                    case '2ndSCSDM_SingleBit'
                        SecondOrderSingleBitSC
                    case '3rd21SCSDM'
                        ThirdOrderCascadeSingleBitSC
                    case '4th211SCSDM'
                        umts211_real_B
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SigmaDeltaMAppLabel = uilabel(app.GridLayout);
            app.SigmaDeltaMAppLabel.Interpreter = 'latex';
            app.SigmaDeltaMAppLabel.Text = '$\Sigma \Delta$M App';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'DV1'; 'DV2'; 'DV3'; 'DV4'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.InputSpecificationsPanel = uipanel(app.GridLayout);
            app.InputSpecificationsPanel.Title = 'Input Specifications';
            app.GridLayout2 = uigridlayout(app.InputSpecificationsPanel);
            app.RuninferenceButton = uibutton(app.GridLayout2, 'push');
            app.RuninferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RuninferenceButtonPushed, true);
            app.RuninferenceButton.Text = 'Run inference';
            app.SNRdBEditFieldLabel = uilabel(app.GridLayout2);
            app.SNRdBEditFieldLabel.Text = 'SNR (dB)';
            app.SNRdBEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SNRdBEditField.AllowEmpty = 'on';
            app.SNRdBEditField.ValueChangedFcn = createCallbackFcn(app, @SNRdBEditFieldValueChanged, true);
            app.BWMHzEditFieldLabel = uilabel(app.GridLayout2);
            app.BWMHzEditFieldLabel.Text = 'BW (MHz)';
            app.BWMHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.BWMHzEditField.AllowEmpty = 'on';
            app.BWMHzEditField.ValueChangedFcn = createCallbackFcn(app, @BWMHzEditFieldValueChanged, true);
            app.PowermWEditFieldLabel = uilabel(app.GridLayout2);
            app.PowermWEditFieldLabel.Text = 'Power (mW)';
            app.PowermWEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PowermWEditField.AllowEmpty = 'on';
            app.PowermWEditField.ValueChangedFcn = createCallbackFcn(app, @PowermWEditFieldValueChanged, true);
            app.OutputSpecificationssimulationsPanel = uipanel(app.GridLayout);
            app.OutputSpecificationssimulationsPanel.Title = 'Output Specifications (simulations)';
            app.GridLayout3 = uigridlayout(app.OutputSpecificationssimulationsPanel);
            app.RunsimulationsButton = uibutton(app.GridLayout3, 'push');
            app.RunsimulationsButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationsButtonPushed, true);
            app.RunsimulationsButton.Enable = 'off';
            app.RunsimulationsButton.Text = 'Run simulations';
            app.woFOMimprovingLabel = uilabel(app.GridLayout3);
            app.woFOMimprovingLabel.Enable = 'off';
            app.woFOMimprovingLabel.Text = 'w/o FOM improving';
            app.wFOMimprovingLabel = uilabel(app.GridLayout3);
            app.wFOMimprovingLabel.Enable = 'off';
            app.wFOMimprovingLabel.Text = 'w/ FOM improving';
            app.TextArea_SNRw = uitextarea(app.GridLayout3);
            app.TextArea_SNRw.Enable = 'off';
            app.TextArea_BWw = uitextarea(app.GridLayout3);
            app.TextArea_BWw.Enable = 'off';
            app.TextArea_Powerw = uitextarea(app.GridLayout3);
            app.TextArea_Powerw.Enable = 'off';
            app.TextArea_SNRwo = uitextarea(app.GridLayout3);
            app.TextArea_SNRwo.Enable = 'off';
            app.TextArea_BWwo = uitextarea(app.GridLayout3);
            app.TextArea_BWwo.Enable = 'off';
            app.TextArea_Powerwo = uitextarea(app.GridLayout3);
            app.TextArea_Powerwo.Enable = 'off';
            app.SpecificationsPanel = uipanel(app.GridLayout);
            app.SpecificationsPanel.Title = 'Specifications';
            app.SelectedArchitecturePanel = uipanel(app.GridLayout);
            app.SelectedArchitecturePanel.Title = 'Selected Architecture';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.RuninferencetoperformarchitectureselectionLabel = uilabel(app.GridLayout);
            app.RuninferencetoperformarchitectureselectionLabel.Text = 'Run inference to perform architecture selection';
            app.DesignVariablesPanel = uipanel(app.GridLayout);
            app.DesignVariablesPanel.Title = 'Design Variables';
            app.RNNInferredDesignVariablesLabel = uilabel(app.GridLayout);
            app.RNNInferredDesignVariablesLabel.Text = 'RNN Inferred Design Variables:';
            app.UITable2 = uitable(app.GridLayout);
            app.UITable2.ColumnName = {'DV1'; 'DV2'; 'DV3'; 'DV4'};
            app.UITable2.RowName = {};
            app.UITable2.Enable = 'off';
            app.ImprovedFOMDesignVariablesLabel = uilabel(app.GridLayout);
            app.ImprovedFOMDesignVariablesLabel.Text = 'Improved FOM Design Variables:';
            app.PowerSpectralDensityGraphPanel = uipanel(app.GridLayout);
            app.PowerSpectralDensityGraphPanel.Title = 'Power Spectral Density Graph';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.Enable = 'off';
            app.GeneratereportButton = uibutton(app.GridLayout, 'push');
            app.GeneratereportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratereportButtonPushed, true);
            app.GeneratereportButton.Enable = 'off';
            app.GeneratereportButton.Text = 'Generate report';
            app.PManrique2024Label = uilabel(app.GridLayout);
            app.PManrique2024Label.Text = 'P. Manrique 2024';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExportsimulationsdataButton = uibutton(app.GridLayout, 'push');
            app.ExportsimulationsdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsimulationsdataButtonPushed, true);
            app.ExportsimulationsdataButton.Enable = 'off';
            app.ExportsimulationsdataButton.Text = 'Export simulations data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDMapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end